classdef BEARapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BEARinterfaceUIFigure           matlab.ui.Figure
        TabGrid                         matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        SPECIFICATIONTab                matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        GridLayout11                    matlab.ui.container.GridLayout
        TabGroup2                       matlab.ui.container.TabGroup
        OLSTab                          matlab.ui.container.Tab
        BayesianTab                     matlab.ui.container.Tab
        BayesianGrid                    matlab.ui.container.GridLayout
        prior_bvar                      matlab.ui.container.ButtonGroup
        DeterministicButton             matlab.ui.control.RadioButton
        IndependentNormalWishartButton  matlab.ui.control.RadioButton
        NormalWishartButton             matlab.ui.control.RadioButton
        DummyObservationsButton         matlab.ui.control.RadioButton
        NormalDiffuseButton             matlab.ui.control.RadioButton
        MinnesotaButton                 matlab.ui.control.RadioButton
        HyperparametersPanel_3          matlab.ui.container.Panel
        GridLayout17_6                  matlab.ui.container.GridLayout
        ExogenousvariableEditField      matlab.ui.control.NumericEditField
        ExogenousvariableEditFieldLabel  matlab.ui.control.Label
        ExcelCheckBox_2                 matlab.ui.control.CheckBox
        lambda3_bvar                    matlab.ui.control.NumericEditField
        LagdecayEditField_2Label        matlab.ui.control.Label
        IGshapeonresidualvarianceEditFieldLabel_6  matlab.ui.control.Label
        IGscaleonresidualvarianceEditFieldLabel_6  matlab.ui.control.Label
        ARcoefficientonresidualvarianceEditFieldLabel_6  matlab.ui.control.Label
        ar_bvar                         matlab.ui.control.NumericEditField
        lambda1_bvar                    matlab.ui.control.NumericEditField
        lambda2_bvar                    matlab.ui.control.NumericEditField
        OptionsPanel                    matlab.ui.container.Panel
        GridLayout17_5                  matlab.ui.container.GridLayout
        TimevaryingIRFsCheckBox_3       matlab.ui.control.CheckBox
        IGshapeonresidualvarianceEditFieldLabel_5  matlab.ui.control.Label
        IGscaleonresidualvarianceEditFieldLabel_5  matlab.ui.control.Label
        ARcoefficientonresidualvarianceEditFieldLabel_5  matlab.ui.control.Label
        IGshapeonfactorvarianceEditFieldLabel_4  matlab.ui.control.Label
        IGscaleonfactorvarianceEditFieldLabel_4  matlab.ui.control.Label
        alpha0_panel_5                  matlab.ui.control.NumericEditField
        delta0_panel_5                  matlab.ui.control.NumericEditField
        gama_panel_5                    matlab.ui.control.NumericEditField
        a0_4                            matlab.ui.control.NumericEditField
        b0_4                            matlab.ui.control.NumericEditField
        Iterations                      matlab.ui.container.Panel
        GridLayout18_4                  matlab.ui.container.GridLayout
        TotalnumberofiterationsEditFieldLabel_4  matlab.ui.control.Label
        NumberofburniniterationsEditFieldLabel_4  matlab.ui.control.Label
        It                              matlab.ui.control.NumericEditField
        Bu                              matlab.ui.control.NumericEditField
        PanelTab                        matlab.ui.container.Tab
        PanelGrid                       matlab.ui.container.GridLayout
        BayesianPanelVARsPriorsButtonGroup  matlab.ui.container.ButtonGroup
        DynamicStructureFactorButton    matlab.ui.control.RadioButton
        RandomEffectHierarchicalButton  matlab.ui.control.RadioButton
        PooledEstimatorButton           matlab.ui.control.RadioButton
        StaticStructureFactorButton     matlab.ui.control.RadioButton
        RandomEffectZellnerHongButton   matlab.ui.control.RadioButton
        MeangroupEstimatorButton        matlab.ui.control.RadioButton
        Iterations_2                    matlab.ui.container.Panel
        GridLayout18_5                  matlab.ui.container.GridLayout
        TotalnumberofiterationsEditFieldLabel_5  matlab.ui.control.Label
        NumberofburniniterationsEditFieldLabel_5  matlab.ui.control.Label
        It_2                            matlab.ui.control.NumericEditField
        Bu_2                            matlab.ui.control.NumericEditField
        EnterlistofunitsPanel           matlab.ui.container.Panel
        GridLayout16                    matlab.ui.container.GridLayout
        EditField                       matlab.ui.control.EditField
        HyperparametersPanel            matlab.ui.container.Panel
        GridLayout17                    matlab.ui.container.GridLayout
        IGshapeonresidualvarianceEditFieldLabel  matlab.ui.control.Label
        IGscaleonresidualvarianceEditFieldLabel  matlab.ui.control.Label
        ARcoefficientonresidualvarianceEditFieldLabel  matlab.ui.control.Label
        IGshapeonfactorvarianceEditFieldLabel  matlab.ui.control.Label
        IGscaleonfactorvarianceEditFieldLabel  matlab.ui.control.Label
        ARcoefficientonfactorEditFieldLabel  matlab.ui.control.Label
        VarianceonMetropolisdrawEditFieldLabel  matlab.ui.control.Label
        alpha0_panel                    matlab.ui.control.NumericEditField
        delta0_panel                    matlab.ui.control.NumericEditField
        gama_panel                      matlab.ui.control.NumericEditField
        a0                              matlab.ui.control.NumericEditField
        b0                              matlab.ui.control.NumericEditField
        rho                             matlab.ui.control.NumericEditField
        psi                             matlab.ui.control.NumericEditField
        HyperparametersPanel_panel      matlab.ui.container.Panel
        GridLayout15                    matlab.ui.container.GridLayout
        AutoregressivecoefficientEditFieldLabel  matlab.ui.control.Label
        OveralltightnessEditFieldLabel  matlab.ui.control.Label
        CrossvariableweightingEditFieldLabel  matlab.ui.control.Label
        LagdecayEditFieldLabel          matlab.ui.control.Label
        ExogenousvariabletightnessEditFieldLabel  matlab.ui.control.Label
        IGshapeonoveralltightnessEditFieldLabel  matlab.ui.control.Label
        IGscaleonoveralltightnessEditFieldLabel  matlab.ui.control.Label
        ar_panel                        matlab.ui.control.NumericEditField
        lambda1_panel                   matlab.ui.control.NumericEditField
        lambda2_panel                   matlab.ui.control.NumericEditField
        lambda3_panel                   matlab.ui.control.NumericEditField
        lambda4_panel                   matlab.ui.control.NumericEditField
        s0                              matlab.ui.control.NumericEditField
        v0                              matlab.ui.control.NumericEditField
        StochasticVolatilityTab         matlab.ui.container.Tab
        SVGrid                          matlab.ui.container.GridLayout
        Iterations_3                    matlab.ui.container.Panel
        GridLayout18_6                  matlab.ui.container.GridLayout
        TotalnumberofiterationsEditFieldLabel_6  matlab.ui.control.Label
        NumberofburniniterationsEditFieldLabel_6  matlab.ui.control.Label
        It_3                            matlab.ui.control.NumericEditField
        Bu_3                            matlab.ui.control.NumericEditField
        StochasticVolatilityButtonGroup  matlab.ui.container.ButtonGroup
        StochasticButton                matlab.ui.control.RadioButton
        LargeVARButton                  matlab.ui.control.RadioButton
        RandomInertiaButton             matlab.ui.control.RadioButton
        StandardButton                  matlab.ui.control.RadioButton
        HyperparametersPanel_2          matlab.ui.container.Panel
        GridLayout17_4                  matlab.ui.container.GridLayout
        ExcelCheckBox                   matlab.ui.control.CheckBox
        ExogeneousvaraiablesSwitch      matlab.ui.control.Switch
        ExogeneousvaraiablesSwitchLabel  matlab.ui.control.Label
        LagdecayEditField               matlab.ui.control.NumericEditField
        LagdecayEditFieldLabel_2        matlab.ui.control.Label
        IGshapeonresidualvarianceEditFieldLabel_4  matlab.ui.control.Label
        IGscaleonresidualvarianceEditFieldLabel_4  matlab.ui.control.Label
        ARcoefficientonresidualvarianceEditFieldLabel_4  matlab.ui.control.Label
        alpha0_panel_4                  matlab.ui.control.NumericEditField
        delta0_panel_4                  matlab.ui.control.NumericEditField
        gama_panel_4                    matlab.ui.control.NumericEditField
        HyperparametersstochasticPanel  matlab.ui.container.Panel
        GridLayout17_3                  matlab.ui.container.GridLayout
        TimevaryingIRFsCheckBox_2       matlab.ui.control.CheckBox
        IGshapeonresidualvarianceEditFieldLabel_3  matlab.ui.control.Label
        IGscaleonresidualvarianceEditFieldLabel_3  matlab.ui.control.Label
        ARcoefficientonresidualvarianceEditFieldLabel_3  matlab.ui.control.Label
        IGshapeonfactorvarianceEditFieldLabel_3  matlab.ui.control.Label
        IGscaleonfactorvarianceEditFieldLabel_3  matlab.ui.control.Label
        alpha0_panel_3                  matlab.ui.control.NumericEditField
        delta0_panel_3                  matlab.ui.control.NumericEditField
        gama_panel_3                    matlab.ui.control.NumericEditField
        a0_3                            matlab.ui.control.NumericEditField
        b0_3                            matlab.ui.control.NumericEditField
        TimeVaryingTab                  matlab.ui.container.Tab
        TVPGrid                         matlab.ui.container.GridLayout
        Iterations_4                    matlab.ui.container.Panel
        GridLayout18_7                  matlab.ui.container.GridLayout
        TotalnumberofiterationsEditFieldLabel_7  matlab.ui.control.Label
        NumberofburniniterationsEditFieldLabel_7  matlab.ui.control.Label
        It_4                            matlab.ui.control.NumericEditField
        Bu_4                            matlab.ui.control.NumericEditField
        TimevaryingButtonGroup          matlab.ui.container.ButtonGroup
        GeneralButton                   matlab.ui.control.RadioButton
        VARcoefficientsButton           matlab.ui.control.RadioButton
        HyperparameterstimevaryingPanel  matlab.ui.container.Panel
        GridLayout17_2                  matlab.ui.container.GridLayout
        TimevaryingIRFsCheckBox         matlab.ui.control.CheckBox
        IGshapeonresidualvarianceEditFieldLabel_2  matlab.ui.control.Label
        IGscaleonresidualvarianceEditFieldLabel_2  matlab.ui.control.Label
        ARcoefficientonresidualvarianceEditFieldLabel_2  matlab.ui.control.Label
        tv_alpha0                       matlab.ui.control.NumericEditField
        tv_delta0                       matlab.ui.control.NumericEditField
        tv_gamma                        matlab.ui.control.NumericEditField
        MixedFrequencyTab               matlab.ui.container.Tab
        SELECTVARTYPELabel              matlab.ui.control.Label
        GridLayout4                     matlab.ui.container.GridLayout
        GridLayout18                    matlab.ui.container.GridLayout
        ExportButton                    matlab.ui.control.Button
        RUNButton                       matlab.ui.control.Button
        GridLayout10                    matlab.ui.container.GridLayout
        SetpathtodataButton             matlab.ui.control.Button
        SetresultsfileButton            matlab.ui.control.StateButton
        SetpathtodataEditField          matlab.ui.control.EditField
        SetresultsfileEditField         matlab.ui.control.EditField
        GridLayout9                     matlab.ui.container.GridLayout
        OutputinExcelCheckBox           matlab.ui.control.CheckBox
        ProduceFiguresCheckBox          matlab.ui.control.CheckBox
        GridLayout8                     matlab.ui.container.GridLayout
        IncludeconstantSwitchLabel      matlab.ui.control.Label
        IncludeconstantSwitch           matlab.ui.control.Switch
        LagsEditFieldLabel              matlab.ui.control.Label
        LagsEditField                   matlab.ui.control.NumericEditField
        GridLayout7                     matlab.ui.container.GridLayout
        EnterthelistofexogeneousvariablesTextArea  matlab.ui.control.TextArea
        EnterthelistofexogeneousvariablesTextAreaLabel  matlab.ui.control.Label
        EnterthelistofendogeneousvariablesTextArea  matlab.ui.control.TextArea
        EnterthelistofendogeneousvariablesTextAreaLabel  matlab.ui.control.Label
        GridLayout6                     matlab.ui.container.GridLayout
        EstimationStartDateDatePicker_2  matlab.ui.control.DatePicker
        EstimationStartDateDatePicker_2Label  matlab.ui.control.Label
        EstimationEndDateDatePicker_2   matlab.ui.control.DatePicker
        EstimationEndDateDatePicker_2Label  matlab.ui.control.Label
        GridLayout5                     matlab.ui.container.GridLayout
        frequency                       matlab.ui.control.DropDown
        DataFrequencyDropDownLabel      matlab.ui.control.Label
        APPLICATIONSTab                 matlab.ui.container.Tab
        GridLayout14                    matlab.ui.container.GridLayout
        FAVARplotPanel                  matlab.ui.container.Panel
        plotXshock                      matlab.ui.control.EditField
        PlotXshockLabel                 matlab.ui.control.Label
        plotX                           matlab.ui.control.EditField
        PlotXLabel                      matlab.ui.control.Label
        ProxyVars                       matlab.ui.container.Panel
        Correlinstrument                matlab.ui.control.EditField
        CorrelInstrumentLabel           matlab.ui.control.Label
        Correlshock                     matlab.ui.control.EditField
        CorrelShockLabel                matlab.ui.control.Label
        prior_type_proxy                matlab.ui.control.Switch
        HighrelevancepriorLabel         matlab.ui.control.Label
        prior_type_reduced_form         matlab.ui.control.Switch
        FlatreducedformpriorLabel       matlab.ui.control.Label
        Instrument                      matlab.ui.control.EditField
        enddateIV                       matlab.ui.control.EditField
        InstrumentEnddateLabel          matlab.ui.control.Label
        startdateIV                     matlab.ui.control.EditField
        InstrumentstartdateLabel        matlab.ui.control.Label
        InstrumentLabel                 matlab.ui.control.Label
        Estimationoptions               matlab.ui.container.Panel
        evaluation_size                 matlab.ui.control.NumericEditField
        EvaluationSizeEditFieldLabel    matlab.ui.control.Label
        window_size                     matlab.ui.control.NumericEditField
        RollingWindow0forfullsampleEditFieldLabel  matlab.ui.control.Label
        hstep                           matlab.ui.control.NumericEditField
        ForecaststepaheadevaluationsEditFieldLabel  matlab.ui.control.Label
        Feval                           matlab.ui.control.Switch
        ForecastevaluationsSwitchLabel  matlab.ui.control.Label
        TypesofconditionalforecastsButtongroup  matlab.ui.container.ButtonGroup
        Tiltinginterval                 matlab.ui.control.RadioButton
        Tiltingmedian                   matlab.ui.control.RadioButton
        Standardshockspecific           matlab.ui.control.RadioButton
        Standardallshocks               matlab.ui.control.RadioButton
        Periodoptions                   matlab.ui.container.Panel
        cband                           matlab.ui.control.NumericEditField
        VARcoefficientsEditFieldLabel   matlab.ui.control.Label
        Fenddate                        matlab.ui.control.EditField
        ForecastsEnddateLabel           matlab.ui.control.Label
        Fstartdate                      matlab.ui.control.EditField
        ForecastsStartdateEditFieldLabel  matlab.ui.control.Label
        Fendsmpl                        matlab.ui.control.Switch
        ForecastsafterlastsampleperiodSwitchLabel  matlab.ui.control.Label
        IRFperiods                      matlab.ui.control.NumericEditField
        IRFperiodsEditFieldLabel        matlab.ui.control.Label
        Applicationoptions              matlab.ui.container.Panel
        favarHDplot                     matlab.ui.control.CheckBox
        favarFEVDplot                   matlab.ui.control.CheckBox
        favarIRFplot                    matlab.ui.control.CheckBox
        FEVD                            matlab.ui.control.Switch
        ForecasterrorvarianceSwitchLabel  matlab.ui.control.Label
        CF                              matlab.ui.control.Switch
        ConditionalforecastsSwitchLabel  matlab.ui.control.Label
        HD                              matlab.ui.control.Switch
        HistoricaldecompositionsSwitchLabel  matlab.ui.control.Label
        F                               matlab.ui.control.Switch
        UnconditionalforecastsSwitchLabel  matlab.ui.control.Label
        IRF                             matlab.ui.control.Switch
        ImpulseresponsefunctionsSwitchLabel  matlab.ui.control.Label
        Interface_Applications          matlab.ui.container.Panel
        Structuralidentifications       matlab.ui.container.Panel
        ButtonGroup                     matlab.ui.container.ButtonGroup
        Proxysign                       matlab.ui.control.RadioButton
        Proxy                           matlab.ui.control.RadioButton
        Signrestrictions                matlab.ui.control.RadioButton
        Triangularfactorisation         matlab.ui.control.RadioButton
        Choleskifactorisation           matlab.ui.control.RadioButton
        Noidentification                matlab.ui.control.RadioButton
        REPLICATIONSTab                 matlab.ui.container.Tab
        ABOUTBEARTab                    matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        Image                           matlab.ui.control.Image
        GridLayout12                    matlab.ui.container.GridLayout
        Label                           matlab.ui.control.Label
        ByAlistairDieppeandBjrnvanRoyeLabel  matlab.ui.control.Label
        BAYESIANESTIMATIONANALYSISANDREGRESSIONBEARTOOLBOXLabel  matlab.ui.control.Label
    end


    properties (Access = private)
        opts (1,1) bear.app.optionsProxyForApp % BEAR settings object
    end
    
    methods (Access = private)
        
        function setProp(app, prop, field, value)
            try
                app.opts.setProp(prop, value);
            catch e
                app.(field).Value = app.opts.opts.(prop);
                uialert(app.BEARinterfaceUIFigure, e.message, "Something went wrong!")
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: TimevaryingButtonGroup
        function TimevaryingButtonGroupSelectionChanged(app, event)
            selectedButton = app.TimevaryingButtonGroup.SelectedObject;
            switch selectedButton.Text
                case "General"
                    app.HyperparameterstimevaryingPanel.Enable = 'on';
                case "VAR coefficients"
                    app.HyperparameterstimevaryingPanel.Enable = 'off';
            end
        end

        % Selection changed function: 
        % StochasticVolatilityButtonGroup
        function StochasticVolatilityButtonGroupSelectionChanged(app, event)
            selectedButton = app.StochasticVolatilityButtonGroup.SelectedObject;
            switch selectedButton.Text
                case "Standard"
                    app.HyperparametersstochasticPanel.Enable = 'on';
                    app.IGshapeonresidualvarianceEditFieldLabel_3.Enable = 'on';
                    app.alpha0_panel_3.Enable = 'on';
                    app.IGscaleonfactorvarianceEditFieldLabel_3.Enable = 'off';
                    app.b0_3.Enable = 'off';
                    app.IGshapeonfactorvarianceEditFieldLabel_3.Enable = 'off';
                    app.a0_3.Enable = 'off';
                case "Random Inertia"
                    app.HyperparametersstochasticPanel.Enable = 'on';
                    app.IGshapeonresidualvarianceEditFieldLabel_3.Enable = 'off';
                    app.alpha0_panel_3.Enable = 'off';
                    app.IGscaleonfactorvarianceEditFieldLabel_3.Enable = 'on';
                    app.b0_3.Enable = 'on';
                    app.IGshapeonfactorvarianceEditFieldLabel_3.Enable = 'on';
                    app.a0_3.Enable = 'on';
                case "Large VAR"
                    app.HyperparametersstochasticPanel.Enable = 'on';
                    app.IGshapeonresidualvarianceEditFieldLabel_3.Enable = 'on';
                    app.alpha0_panel_3.Enable = 'on';
                    app.IGscaleonfactorvarianceEditFieldLabel_3.Enable = 'off';
                    app.b0_3.Enable = 'off';
                    app.IGshapeonfactorvarianceEditFieldLabel_3.Enable = 'off';
                    app.a0_3.Enable = 'off';
                case "Stochastic"
                    app.HyperparametersstochasticPanel.Enable = 'off';
            end
        end

        % Selection change function: TabGroup2
        function TabGroup2SelectionChanged(app, event)
            selectedTab = app.TabGroup2.SelectedTab;
            switch selectedTab.Title
                case "OLS"
                    app.opts.VARtype = 1;
                case "Bayesian"
                    app.opts.VARtype = 2;
                case "Panel"
                    app.opts.VARtype = 4;
                case "Stochastic Volatility"
                    app.opts.VARtype = 5;
                case "Time Varying"
                    app.opts.VARtype = 6;
            end
        end

        % Value changed function: It
        function ItValueChanged(app, event)
            app.opts.It = app.It.Value;
        end

        % Value changed function: Bu
        function BuValueChanged(app, event)
            app.opts.Bu = app.Bu.Value;
        end

        % Value changed function: It_2
        function It_2ValueChanged(app, event)
            app.opts.It = app.It_2.Value;
        end

        % Value changed function: Bu_2
        function Bu_2ValueChanged(app, event)
            app.opts.Bu = app.Bu_2.Value;
        end

        % Value changed function: It_3
        function It_3ValueChanged(app, event)
            app.opts.It = app.It_3.Value;
        end

        % Value changed function: Bu_3
        function Bu_3ValueChanged(app, event)
            app.opts.Bu = app.Bu_3.Value;
        end

        % Value changed function: It_4
        function It_4ValueChanged(app, event)
            app.opts.It = app.It_4.Value;
        end

        % Value changed function: Bu_4
        function Bu_4ValueChanged(app, event)
            app.opts.Bu = app.Bu_4.Value;
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            try
                BEARmain(app.opts.opts)
            catch e
                uialert(app.BEARinterfaceUIFigure, e.message, 'Something went wrong!')
            end
        end

        % Value changed function: CF
        function CFValueChanged(app, event)
            switch app.CF.Value
                case 'on'
                    opts.CF = true;
            end
            
        end

        % Value changed function: EstimationStartDateDatePicker_2
        function EstimationStartDateDatePicker_2ValueChanged(app, event)
            value = app.EstimationStartDateDatePicker_2.Value;
            app.opts.setCommonProp('startdate', strrep(char(value),'Q','q'));
        end

        % Value changed function: EstimationEndDateDatePicker_2
        function EstimationEndDateDatePicker_2ValueChanged(app, event)
            value = app.EstimationEndDateDatePicker_2.Value;
            app.opts.setCommonProp('enddate', strrep(char(value),'Q','q'));
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            assignin("base", 'opts', app.opts.opts)
        end

        % Value changed function: 
        % EnterthelistofendogeneousvariablesTextArea
        function EnterthelistofendogeneousvariablesTextAreaValueChanged(app, event)
            value = app.EnterthelistofendogeneousvariablesTextArea.Value;
            app.opts.setCommonProp('varendo', value{1});
        end

        % Value changed function: 
        % EnterthelistofexogeneousvariablesTextArea
        function EnterthelistofexogeneousvariablesTextAreaValueChanged(app, event)
            value = app.EnterthelistofexogeneousvariablesTextArea.Value;
            app.opts.setCommonProp('varexo', value{1});
        end

        % Value changed function: LagsEditField
        function LagsEditFieldValueChanged(app, event)
            app.opts.setCommonProp('lags', app.LagsEditField.Value);
        end

        % Value changed function: OutputinExcelCheckBox
        function OutputinExcelCheckBoxValueChanged(app, event)
            value = app.OutputinExcelCheckBox.Value;
            pref = app.opts.opts.pref;
            pref.results = value;
            app.opts.setCommonProp('pref', pref);
        end

        % Value changed function: ProduceFiguresCheckBox
        function ProduceFiguresCheckBoxValueChanged(app, event)
            value = app.ProduceFiguresCheckBox.Value;
            pref = app.opts.opts.pref;
            pref.plot = value;
            app.opts.setCommonProp('pref', pref);
        end

        % Value changed function: IncludeconstantSwitch
        function IncludeconstantSwitchValueChanged(app, event)
            value = app.IncludeconstantSwitch.Value;
            switch value
                case 'on'
                    app.opts.setCommonProp('const', true);
                case 'off'
                    app.opts.setCommonProp('const', false);
            end
        end

        % Value changed function: SetresultsfileButton
        function SetresultsfileButtonValueChanged(app, event)
            selpath = uigetdir(pwd);
            if ~isequal(selpath,0)
                pref = app.opts.opts.pref;
                pref.results_path = selpath;
                app.opts.setCommonProp('pref', pref);
            end            
        end

        % Button pushed function: SetpathtodataButton
        function SetpathtodataButtonPushed(app, event)
            [file,path] = uigetfile('*.xlsx');
            if ~isequal(file,0)
                app.SetpathtodataEditField.Value = fullfile(path,file);
                pref = app.opts.opts.pref;
                pref.excelFile = fullfile(path,file);
                app.opts.setCommonProp('pref', pref);
            end            
        end

        % Value changed function: SetresultsfileEditField
        function SetresultsfileEditFieldValueChanged(app, event)
            value = app.SetresultsfileEditField.Value;
            pref = app.opts.opts.pref;
            pref.results_sub = value;
            app.opts.setCommonProp('pref', pref);
        end

        % Value changed function: frequency
        function frequencyValueChanged(app, event)
            value = app.frequency.Value;
            app.opts.setCommonProp('frequency', value);
        end

        % Value changed function: ar_bvar
        function ar_bvarValueChanged(app, event)
            value = app.ar_bvar.Value;
            app.setProp('ar', 'ar_bvar', value);
        end

        % Value changed function: lambda1_bvar
        function lambda1_bvarValueChanged(app, event)
            value = app.lambda1_bvar.Value;
            app.setProp('lambda1', 'lambda1_bvar', value);
        end

        % Value changed function: lambda2_bvar
        function lambda2_bvarValueChanged(app, event)
            value = app.lambda2_bvar.Value;
            app.setProp('lambda2', 'lambda2_bvar', value);
        end

        % Value changed function: lambda3_bvar
        function lambda3_bvarValueChanged(app, event)
            value = app.lambda3_bvar.Value;
            app.setProp('lambda3', 'lambda3_bvar', value);
        end

        % Value changed function: ExogenousvariableEditField
        function ExogenousvariableEditFieldValueChanged(app, event)
            value = app.lambda4_bvar.Value;
            app.setProp('lambda4', 'lambda4_bvar', value);
        end

        % Selection changed function: prior_bvar
        function prior_bvarSelectionChanged(app, event)

            switch event.NewValue.Text
                case "Minnesota"
                    prior = bear.PRIORtype('Minnesota_univariate');
                case "Normal Diffuse"
                    prior = bear.PRIORtype('Nd');
                case "Dummy Observations"                    
                    prior = bear.PRIORtype('Dummy');
                case "Normal Wishart"                    
                    prior = bear.PRIORtype('Nw_univariate');
                case "Independent Normal Wishart"                    
                    prior = bear.PRIORtype('Inw_univariate');
                case "Deterministic"
                    prior = bear.PRIORtype('Madj');
            end
            app.setProp('prior', 'prior_bvar', prior);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BEARinterfaceUIFigure = uifigure('Visible', 'off');
            app.BEARinterfaceUIFigure.Name = 'BEAR interface';
            app.BEARinterfaceUIFigure.Icon = 'bear-coloured1.png';
            app.TabGrid = uigridlayout(app.BEARinterfaceUIFigure);
            app.TabGroup = uitabgroup(app.TabGrid);
            app.SPECIFICATIONTab = uitab(app.TabGroup);
            app.SPECIFICATIONTab.Title = 'SPECIFICATION';
            app.GridLayout2 = uigridlayout(app.SPECIFICATIONTab);
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.DataFrequencyDropDownLabel = uilabel(app.GridLayout5);
            app.DataFrequencyDropDownLabel.Text = 'Data Frequency';
            app.frequency = uidropdown(app.GridLayout5);
            app.frequency.Items = {'yearly', 'quarterly', 'monthly', 'weekly', 'daily', 'undated'};
            app.frequency.ItemsData = {'1', '2', '3', '4', '5', '6'};
            app.frequency.ValueChangedFcn = createCallbackFcn(app, @frequencyValueChanged, true);
            app.frequency.Value = '2';
            app.GridLayout6 = uigridlayout(app.GridLayout4);
            app.EstimationEndDateDatePicker_2Label = uilabel(app.GridLayout6);
            app.EstimationEndDateDatePicker_2Label.Text = 'Estimation End Date';
            app.EstimationEndDateDatePicker_2 = uidatepicker(app.GridLayout6);
            app.EstimationEndDateDatePicker_2.Limits = [datetime([1900 1 1]) datetime([9999 12 31])];
            app.EstimationEndDateDatePicker_2.DisplayFormat = 'yyyyQQQ';
            app.EstimationEndDateDatePicker_2.ValueChangedFcn = createCallbackFcn(app, @EstimationEndDateDatePicker_2ValueChanged, true);
            app.EstimationEndDateDatePicker_2.Value = datetime([2014 12 1]);
            app.EstimationStartDateDatePicker_2Label = uilabel(app.GridLayout6);
            app.EstimationStartDateDatePicker_2Label.Text = 'Estimation Start Date';
            app.EstimationStartDateDatePicker_2 = uidatepicker(app.GridLayout6);
            app.EstimationStartDateDatePicker_2.DisplayFormat = 'yyyyQQQ';
            app.EstimationStartDateDatePicker_2.ValueChangedFcn = createCallbackFcn(app, @EstimationStartDateDatePicker_2ValueChanged, true);
            app.EstimationStartDateDatePicker_2.Value = datetime([1974 1 1]);
            app.GridLayout7 = uigridlayout(app.GridLayout4);
            app.EnterthelistofendogeneousvariablesTextAreaLabel = uilabel(app.GridLayout7);
            app.EnterthelistofendogeneousvariablesTextAreaLabel.Text = 'Enter the list of endogeneous variables';
            app.EnterthelistofendogeneousvariablesTextArea = uitextarea(app.GridLayout7);
            app.EnterthelistofendogeneousvariablesTextArea.ValueChangedFcn = createCallbackFcn(app, @EnterthelistofendogeneousvariablesTextAreaValueChanged, true);
            app.EnterthelistofendogeneousvariablesTextArea.Value = {'DOM_GDP DOM_CPI STN'};
            app.EnterthelistofexogeneousvariablesTextAreaLabel = uilabel(app.GridLayout7);
            app.EnterthelistofexogeneousvariablesTextAreaLabel.Text = 'Enter the list of exogeneous variables';
            app.EnterthelistofexogeneousvariablesTextArea = uitextarea(app.GridLayout7);
            app.EnterthelistofexogeneousvariablesTextArea.ValueChangedFcn = createCallbackFcn(app, @EnterthelistofexogeneousvariablesTextAreaValueChanged, true);
            app.GridLayout8 = uigridlayout(app.GridLayout4);
            app.LagsEditField = uieditfield(app.GridLayout8, 'numeric');
            app.LagsEditField.ValueChangedFcn = createCallbackFcn(app, @LagsEditFieldValueChanged, true);
            app.LagsEditField.Value = 4;
            app.LagsEditFieldLabel = uilabel(app.GridLayout8);
            app.LagsEditFieldLabel.Text = 'Lags';
            app.IncludeconstantSwitch = uiswitch(app.GridLayout8, 'slider');
            app.IncludeconstantSwitch.ValueChangedFcn = createCallbackFcn(app, @IncludeconstantSwitchValueChanged, true);
            app.IncludeconstantSwitch.Value = 'On';
            app.IncludeconstantSwitchLabel = uilabel(app.GridLayout8);
            app.IncludeconstantSwitchLabel.Text = 'Include constant';
            app.GridLayout9 = uigridlayout(app.GridLayout4);
            app.ProduceFiguresCheckBox = uicheckbox(app.GridLayout9);
            app.ProduceFiguresCheckBox.ValueChangedFcn = createCallbackFcn(app, @ProduceFiguresCheckBoxValueChanged, true);
            app.ProduceFiguresCheckBox.Text = 'Produce Figures';
            app.OutputinExcelCheckBox = uicheckbox(app.GridLayout9);
            app.OutputinExcelCheckBox.ValueChangedFcn = createCallbackFcn(app, @OutputinExcelCheckBoxValueChanged, true);
            app.OutputinExcelCheckBox.Text = 'Output in Excel';
            app.GridLayout10 = uigridlayout(app.GridLayout4);
            app.SetresultsfileEditField = uieditfield(app.GridLayout10, 'text');
            app.SetresultsfileEditField.ValueChangedFcn = createCallbackFcn(app, @SetresultsfileEditFieldValueChanged, true);
            app.SetpathtodataEditField = uieditfield(app.GridLayout10, 'text');
            app.SetresultsfileButton = uibutton(app.GridLayout10, 'state');
            app.SetresultsfileButton.ValueChangedFcn = createCallbackFcn(app, @SetresultsfileButtonValueChanged, true);
            app.SetresultsfileButton.Text = 'Set results file';
            app.SetpathtodataButton = uibutton(app.GridLayout10, 'push');
            app.SetpathtodataButton.ButtonPushedFcn = createCallbackFcn(app, @SetpathtodataButtonPushed, true);
            app.SetpathtodataButton.Text = 'Set path to data';
            app.GridLayout18 = uigridlayout(app.GridLayout4);
            app.RUNButton = uibutton(app.GridLayout18, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Icon = 'BEAR.PNG';
            app.RUNButton.Text = 'RUN';
            app.ExportButton = uibutton(app.GridLayout18, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.GridLayout11 = uigridlayout(app.GridLayout2);
            app.SELECTVARTYPELabel = uilabel(app.GridLayout11);
            app.SELECTVARTYPELabel.Text = 'SELECT VAR TYPE';
            app.TabGroup2 = uitabgroup(app.GridLayout11);
            app.TabGroup2.SelectionChangedFcn = createCallbackFcn(app, @TabGroup2SelectionChanged, true);
            app.OLSTab = uitab(app.TabGroup2);
            app.OLSTab.Title = 'OLS';
            app.BayesianTab = uitab(app.TabGroup2);
            app.BayesianTab.Title = 'Bayesian';
            app.BayesianGrid = uigridlayout(app.BayesianTab);
            app.Iterations = uipanel(app.BayesianGrid);
            app.Iterations.Title = 'Iterations';
            app.GridLayout18_4 = uigridlayout(app.Iterations);
            app.Bu = uieditfield(app.GridLayout18_4, 'numeric');
            app.Bu.ValueChangedFcn = createCallbackFcn(app, @BuValueChanged, true);
            app.Bu.Value = 1000;
            app.It = uieditfield(app.GridLayout18_4, 'numeric');
            app.It.ValueChangedFcn = createCallbackFcn(app, @ItValueChanged, true);
            app.It.Value = 2000;
            app.NumberofburniniterationsEditFieldLabel_4 = uilabel(app.GridLayout18_4);
            app.NumberofburniniterationsEditFieldLabel_4.Text = 'Number of burn-in iterations';
            app.TotalnumberofiterationsEditFieldLabel_4 = uilabel(app.GridLayout18_4);
            app.TotalnumberofiterationsEditFieldLabel_4.Text = 'Total number of iterations:';
            app.OptionsPanel = uipanel(app.BayesianGrid);
            app.OptionsPanel.Title = 'Options';
            app.GridLayout17_5 = uigridlayout(app.OptionsPanel);
            app.b0_4 = uieditfield(app.GridLayout17_5, 'numeric');
            app.b0_4.Enable = 'off';
            app.a0_4 = uieditfield(app.GridLayout17_5, 'numeric');
            app.a0_4.Enable = 'off';
            app.gama_panel_5 = uieditfield(app.GridLayout17_5, 'numeric');
            app.delta0_panel_5 = uieditfield(app.GridLayout17_5, 'numeric');
            app.alpha0_panel_5 = uieditfield(app.GridLayout17_5, 'numeric');
            app.alpha0_panel_5.Value = 1;
            app.IGscaleonfactorvarianceEditFieldLabel_4 = uilabel(app.GridLayout17_5);
            app.IGscaleonfactorvarianceEditFieldLabel_4.Enable = 'off';
            app.IGscaleonfactorvarianceEditFieldLabel_4.Text = 'Prior variance on inertia';
            app.IGshapeonfactorvarianceEditFieldLabel_4 = uilabel(app.GridLayout17_5);
            app.IGshapeonfactorvarianceEditFieldLabel_4.Enable = 'off';
            app.IGshapeonfactorvarianceEditFieldLabel_4.Text = 'Prior mean on inertia';
            app.ARcoefficientonresidualvarianceEditFieldLabel_5 = uilabel(app.GridLayout17_5);
            app.ARcoefficientonresidualvarianceEditFieldLabel_5.Text = 'IG scale on residual variance';
            app.IGscaleonresidualvarianceEditFieldLabel_5 = uilabel(app.GridLayout17_5);
            app.IGscaleonresidualvarianceEditFieldLabel_5.Text = 'IG shape on residual variance';
            app.IGshapeonresidualvarianceEditFieldLabel_5 = uilabel(app.GridLayout17_5);
            app.IGshapeonresidualvarianceEditFieldLabel_5.Text = 'AR coefficient on residual variance';
            app.TimevaryingIRFsCheckBox_3 = uicheckbox(app.GridLayout17_5);
            app.TimevaryingIRFsCheckBox_3.Text = 'Time-varying IRFs';
            app.TimevaryingIRFsCheckBox_3.Value = true;
            app.HyperparametersPanel_3 = uipanel(app.BayesianGrid);
            app.HyperparametersPanel_3.Title = 'Hyperparameters';
            app.GridLayout17_6 = uigridlayout(app.HyperparametersPanel_3);
            app.lambda2_bvar = uieditfield(app.GridLayout17_6, 'numeric');
            app.lambda2_bvar.ValueChangedFcn = createCallbackFcn(app, @lambda2_bvarValueChanged, true);
            app.lambda2_bvar.Value = 1;
            app.lambda1_bvar = uieditfield(app.GridLayout17_6, 'numeric');
            app.lambda1_bvar.ValueChangedFcn = createCallbackFcn(app, @lambda1_bvarValueChanged, true);
            app.lambda1_bvar.Value = 0.1;
            app.ar_bvar = uieditfield(app.GridLayout17_6, 'numeric');
            app.ar_bvar.ValueChangedFcn = createCallbackFcn(app, @ar_bvarValueChanged, true);
            app.ar_bvar.Value = 0.8;
            app.ARcoefficientonresidualvarianceEditFieldLabel_6 = uilabel(app.GridLayout17_6);
            app.ARcoefficientonresidualvarianceEditFieldLabel_6.Text = 'Cross-variable weighting';
            app.IGscaleonresidualvarianceEditFieldLabel_6 = uilabel(app.GridLayout17_6);
            app.IGscaleonresidualvarianceEditFieldLabel_6.Text = 'Overall tightness';
            app.IGshapeonresidualvarianceEditFieldLabel_6 = uilabel(app.GridLayout17_6);
            app.IGshapeonresidualvarianceEditFieldLabel_6.Text = 'Autoregresive coefficient';
            app.LagdecayEditField_2Label = uilabel(app.GridLayout17_6);
            app.LagdecayEditField_2Label.Text = 'Lag decay';
            app.lambda3_bvar = uieditfield(app.GridLayout17_6, 'numeric');
            app.lambda3_bvar.ValueChangedFcn = createCallbackFcn(app, @lambda3_bvarValueChanged, true);
            app.ExcelCheckBox_2 = uicheckbox(app.GridLayout17_6);
            app.ExcelCheckBox_2.Text = 'Excel';
            app.ExogenousvariableEditFieldLabel = uilabel(app.GridLayout17_6);
            app.ExogenousvariableEditFieldLabel.Text = 'Exogenous variable';
            app.ExogenousvariableEditField = uieditfield(app.GridLayout17_6, 'numeric');
            app.ExogenousvariableEditField.ValueChangedFcn = createCallbackFcn(app, @ExogenousvariableEditFieldValueChanged, true);
            app.prior_bvar = uibuttongroup(app.BayesianGrid);
            app.prior_bvar.SelectionChangedFcn = createCallbackFcn(app, @prior_bvarSelectionChanged, true);
            app.prior_bvar.Title = 'Bayesian VARs Priors';
            app.MinnesotaButton = uiradiobutton(app.prior_bvar);
            app.MinnesotaButton.Text = 'Minnesota';
            app.MinnesotaButton.Value = true;
            app.NormalDiffuseButton = uiradiobutton(app.prior_bvar);
            app.NormalDiffuseButton.Text = 'Normal Diffuse';
            app.DummyObservationsButton = uiradiobutton(app.prior_bvar);
            app.DummyObservationsButton.Text = 'Dummy Observations';
            app.NormalWishartButton = uiradiobutton(app.prior_bvar);
            app.NormalWishartButton.Text = 'Normal Wishart';
            app.IndependentNormalWishartButton = uiradiobutton(app.prior_bvar);
            app.IndependentNormalWishartButton.Text = 'Independent Normal Wishart';
            app.DeterministicButton = uiradiobutton(app.prior_bvar);
            app.DeterministicButton.Text = 'Deterministic';
            app.PanelTab = uitab(app.TabGroup2);
            app.PanelTab.Title = 'Panel';
            app.PanelGrid = uigridlayout(app.PanelTab);
            app.HyperparametersPanel_panel = uipanel(app.PanelGrid);
            app.HyperparametersPanel_panel.Title = 'Hyperparameters';
            app.GridLayout15 = uigridlayout(app.HyperparametersPanel_panel);
            app.v0 = uieditfield(app.GridLayout15, 'numeric');
            app.v0.Value = 0.001;
            app.s0 = uieditfield(app.GridLayout15, 'numeric');
            app.s0.Value = 0.001;
            app.lambda4_panel = uieditfield(app.GridLayout15, 'numeric');
            app.lambda4_panel.Value = 100;
            app.lambda3_panel = uieditfield(app.GridLayout15, 'numeric');
            app.lambda3_panel.Value = 1;
            app.lambda2_panel = uieditfield(app.GridLayout15, 'numeric');
            app.lambda2_panel.Value = 0.5;
            app.lambda1_panel = uieditfield(app.GridLayout15, 'numeric');
            app.lambda1_panel.Value = 0.1;
            app.ar_panel = uieditfield(app.GridLayout15, 'numeric');
            app.ar_panel.Value = 0.8;
            app.IGscaleonoveralltightnessEditFieldLabel = uilabel(app.GridLayout15);
            app.IGscaleonoveralltightnessEditFieldLabel.Text = 'IG scale on overall tightness';
            app.IGshapeonoveralltightnessEditFieldLabel = uilabel(app.GridLayout15);
            app.IGshapeonoveralltightnessEditFieldLabel.Text = 'IG shape on overall tightness';
            app.ExogenousvariabletightnessEditFieldLabel = uilabel(app.GridLayout15);
            app.ExogenousvariabletightnessEditFieldLabel.Text = 'Exogenous variable tightness';
            app.LagdecayEditFieldLabel = uilabel(app.GridLayout15);
            app.LagdecayEditFieldLabel.Text = 'Lag decay';
            app.CrossvariableweightingEditFieldLabel = uilabel(app.GridLayout15);
            app.CrossvariableweightingEditFieldLabel.Text = 'Cross-variable weighting';
            app.OveralltightnessEditFieldLabel = uilabel(app.GridLayout15);
            app.OveralltightnessEditFieldLabel.Text = 'Overall tightness';
            app.AutoregressivecoefficientEditFieldLabel = uilabel(app.GridLayout15);
            app.AutoregressivecoefficientEditFieldLabel.Text = 'Autoregressive coefficient';
            app.HyperparametersPanel = uipanel(app.PanelGrid);
            app.HyperparametersPanel.Title = 'Hyperparameters';
            app.GridLayout17 = uigridlayout(app.HyperparametersPanel);
            app.psi = uieditfield(app.GridLayout17, 'numeric');
            app.psi.Value = 0.1;
            app.rho = uieditfield(app.GridLayout17, 'numeric');
            app.rho.Value = 0.75;
            app.b0 = uieditfield(app.GridLayout17, 'numeric');
            app.b0.Value = 1;
            app.a0 = uieditfield(app.GridLayout17, 'numeric');
            app.a0.Value = 1000;
            app.gama_panel = uieditfield(app.GridLayout17, 'numeric');
            app.gama_panel.Value = 0.85;
            app.delta0_panel = uieditfield(app.GridLayout17, 'numeric');
            app.delta0_panel.Value = 1;
            app.alpha0_panel = uieditfield(app.GridLayout17, 'numeric');
            app.alpha0_panel.Value = 1000;
            app.VarianceonMetropolisdrawEditFieldLabel = uilabel(app.GridLayout17);
            app.VarianceonMetropolisdrawEditFieldLabel.Text = 'Variance on Metropolis draw';
            app.ARcoefficientonfactorEditFieldLabel = uilabel(app.GridLayout17);
            app.ARcoefficientonfactorEditFieldLabel.Text = 'AR coefficient on factor';
            app.IGscaleonfactorvarianceEditFieldLabel = uilabel(app.GridLayout17);
            app.IGscaleonfactorvarianceEditFieldLabel.Text = 'IG scale on factor variance';
            app.IGshapeonfactorvarianceEditFieldLabel = uilabel(app.GridLayout17);
            app.IGshapeonfactorvarianceEditFieldLabel.Text = 'IG shape on factor variance';
            app.ARcoefficientonresidualvarianceEditFieldLabel = uilabel(app.GridLayout17);
            app.ARcoefficientonresidualvarianceEditFieldLabel.Text = 'AR coefficient on residual variance';
            app.IGscaleonresidualvarianceEditFieldLabel = uilabel(app.GridLayout17);
            app.IGscaleonresidualvarianceEditFieldLabel.Text = 'IG scale on residual variance';
            app.IGshapeonresidualvarianceEditFieldLabel = uilabel(app.GridLayout17);
            app.IGshapeonresidualvarianceEditFieldLabel.Text = 'IG shape on residual variance';
            app.EnterlistofunitsPanel = uipanel(app.PanelGrid);
            app.EnterlistofunitsPanel.Title = 'Enter list of units';
            app.GridLayout16 = uigridlayout(app.EnterlistofunitsPanel);
            app.EditField = uieditfield(app.GridLayout16, 'text');
            app.Iterations_2 = uipanel(app.PanelGrid);
            app.Iterations_2.Title = 'Iterations';
            app.GridLayout18_5 = uigridlayout(app.Iterations_2);
            app.Bu_2 = uieditfield(app.GridLayout18_5, 'numeric');
            app.Bu_2.ValueChangedFcn = createCallbackFcn(app, @Bu_2ValueChanged, true);
            app.Bu_2.Value = 1000;
            app.It_2 = uieditfield(app.GridLayout18_5, 'numeric');
            app.It_2.ValueChangedFcn = createCallbackFcn(app, @It_2ValueChanged, true);
            app.It_2.Value = 2000;
            app.NumberofburniniterationsEditFieldLabel_5 = uilabel(app.GridLayout18_5);
            app.NumberofburniniterationsEditFieldLabel_5.Text = 'Number of burn-in iterations';
            app.TotalnumberofiterationsEditFieldLabel_5 = uilabel(app.GridLayout18_5);
            app.TotalnumberofiterationsEditFieldLabel_5.Text = 'Total number of iterations:';
            app.BayesianPanelVARsPriorsButtonGroup = uibuttongroup(app.PanelGrid);
            app.BayesianPanelVARsPriorsButtonGroup.Title = 'Bayesian Panel VARs Priors';
            app.MeangroupEstimatorButton = uiradiobutton(app.BayesianPanelVARsPriorsButtonGroup);
            app.MeangroupEstimatorButton.Text = 'Mean group Estimator';
            app.MeangroupEstimatorButton.Value = true;
            app.RandomEffectZellnerHongButton = uiradiobutton(app.BayesianPanelVARsPriorsButtonGroup);
            app.RandomEffectZellnerHongButton.Text = 'Random Effect (Zellner-Hong)';
            app.StaticStructureFactorButton = uiradiobutton(app.BayesianPanelVARsPriorsButtonGroup);
            app.StaticStructureFactorButton.Text = 'Static Structure Factor';
            app.PooledEstimatorButton = uiradiobutton(app.BayesianPanelVARsPriorsButtonGroup);
            app.PooledEstimatorButton.Text = 'Pooled Estimator';
            app.RandomEffectHierarchicalButton = uiradiobutton(app.BayesianPanelVARsPriorsButtonGroup);
            app.RandomEffectHierarchicalButton.Text = 'Random Effect (Hierarchical)';
            app.DynamicStructureFactorButton = uiradiobutton(app.BayesianPanelVARsPriorsButtonGroup);
            app.DynamicStructureFactorButton.Text = 'Dynamic Structure Factor';
            app.StochasticVolatilityTab = uitab(app.TabGroup2);
            app.StochasticVolatilityTab.Title = 'Stochastic Volatility';
            app.SVGrid = uigridlayout(app.StochasticVolatilityTab);
            app.HyperparametersstochasticPanel = uipanel(app.SVGrid);
            app.HyperparametersstochasticPanel.Title = 'Hyperparameters stochastic';
            app.GridLayout17_3 = uigridlayout(app.HyperparametersstochasticPanel);
            app.b0_3 = uieditfield(app.GridLayout17_3, 'numeric');
            app.b0_3.Enable = 'off';
            app.a0_3 = uieditfield(app.GridLayout17_3, 'numeric');
            app.a0_3.Enable = 'off';
            app.gama_panel_3 = uieditfield(app.GridLayout17_3, 'numeric');
            app.delta0_panel_3 = uieditfield(app.GridLayout17_3, 'numeric');
            app.alpha0_panel_3 = uieditfield(app.GridLayout17_3, 'numeric');
            app.alpha0_panel_3.Value = 1;
            app.IGscaleonfactorvarianceEditFieldLabel_3 = uilabel(app.GridLayout17_3);
            app.IGscaleonfactorvarianceEditFieldLabel_3.Enable = 'off';
            app.IGscaleonfactorvarianceEditFieldLabel_3.Text = 'Prior variance on inertia';
            app.IGshapeonfactorvarianceEditFieldLabel_3 = uilabel(app.GridLayout17_3);
            app.IGshapeonfactorvarianceEditFieldLabel_3.Enable = 'off';
            app.IGshapeonfactorvarianceEditFieldLabel_3.Text = 'Prior mean on inertia';
            app.ARcoefficientonresidualvarianceEditFieldLabel_3 = uilabel(app.GridLayout17_3);
            app.ARcoefficientonresidualvarianceEditFieldLabel_3.Text = 'IG scale on residual variance';
            app.IGscaleonresidualvarianceEditFieldLabel_3 = uilabel(app.GridLayout17_3);
            app.IGscaleonresidualvarianceEditFieldLabel_3.Text = 'IG shape on residual variance';
            app.IGshapeonresidualvarianceEditFieldLabel_3 = uilabel(app.GridLayout17_3);
            app.IGshapeonresidualvarianceEditFieldLabel_3.Text = 'AR coefficient on residual variance';
            app.TimevaryingIRFsCheckBox_2 = uicheckbox(app.GridLayout17_3);
            app.TimevaryingIRFsCheckBox_2.Text = 'Time-varying IRFs';
            app.TimevaryingIRFsCheckBox_2.Value = true;
            app.HyperparametersPanel_2 = uipanel(app.SVGrid);
            app.HyperparametersPanel_2.Title = 'Hyperparameters';
            app.GridLayout17_4 = uigridlayout(app.HyperparametersPanel_2);
            app.gama_panel_4 = uieditfield(app.GridLayout17_4, 'numeric');
            app.gama_panel_4.Value = 1;
            app.delta0_panel_4 = uieditfield(app.GridLayout17_4, 'numeric');
            app.delta0_panel_4.Value = 0.1;
            app.alpha0_panel_4 = uieditfield(app.GridLayout17_4, 'numeric');
            app.alpha0_panel_4.Value = 0.8;
            app.ARcoefficientonresidualvarianceEditFieldLabel_4 = uilabel(app.GridLayout17_4);
            app.ARcoefficientonresidualvarianceEditFieldLabel_4.Text = 'Cross-variable weighting';
            app.IGscaleonresidualvarianceEditFieldLabel_4 = uilabel(app.GridLayout17_4);
            app.IGscaleonresidualvarianceEditFieldLabel_4.Text = 'Overall tightness';
            app.IGshapeonresidualvarianceEditFieldLabel_4 = uilabel(app.GridLayout17_4);
            app.IGshapeonresidualvarianceEditFieldLabel_4.Text = 'Autoregresive coefficient';
            app.LagdecayEditFieldLabel_2 = uilabel(app.GridLayout17_4);
            app.LagdecayEditFieldLabel_2.Text = 'Lag decay';
            app.LagdecayEditField = uieditfield(app.GridLayout17_4, 'numeric');
            app.ExogeneousvaraiablesSwitchLabel = uilabel(app.GridLayout17_4);
            app.ExogeneousvaraiablesSwitchLabel.Text = 'Exogeneous varaiables';
            app.ExogeneousvaraiablesSwitch = uiswitch(app.GridLayout17_4, 'slider');
            app.ExogeneousvaraiablesSwitch.Items = {'Excel', 'Default'};
            app.ExogeneousvaraiablesSwitch.Value = 'Excel';
            app.ExcelCheckBox = uicheckbox(app.GridLayout17_4);
            app.ExcelCheckBox.Text = 'Excel';
            app.StochasticVolatilityButtonGroup = uibuttongroup(app.SVGrid);
            app.StochasticVolatilityButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @StochasticVolatilityButtonGroupSelectionChanged, true);
            app.StochasticVolatilityButtonGroup.Title = 'Stochastic Volatility';
            app.StandardButton = uiradiobutton(app.StochasticVolatilityButtonGroup);
            app.StandardButton.Text = 'Standard';
            app.StandardButton.Value = true;
            app.RandomInertiaButton = uiradiobutton(app.StochasticVolatilityButtonGroup);
            app.RandomInertiaButton.Text = 'Random Inertia';
            app.LargeVARButton = uiradiobutton(app.StochasticVolatilityButtonGroup);
            app.LargeVARButton.Text = 'Large VAR';
            app.StochasticButton = uiradiobutton(app.StochasticVolatilityButtonGroup);
            app.StochasticButton.Text = 'Stochastic';
            app.Iterations_3 = uipanel(app.SVGrid);
            app.Iterations_3.Title = 'Iterations';
            app.GridLayout18_6 = uigridlayout(app.Iterations_3);
            app.Bu_3 = uieditfield(app.GridLayout18_6, 'numeric');
            app.Bu_3.ValueChangedFcn = createCallbackFcn(app, @Bu_3ValueChanged, true);
            app.Bu_3.Value = 1000;
            app.It_3 = uieditfield(app.GridLayout18_6, 'numeric');
            app.It_3.ValueChangedFcn = createCallbackFcn(app, @It_3ValueChanged, true);
            app.It_3.Value = 2000;
            app.NumberofburniniterationsEditFieldLabel_6 = uilabel(app.GridLayout18_6);
            app.NumberofburniniterationsEditFieldLabel_6.Text = 'Number of burn-in iterations';
            app.TotalnumberofiterationsEditFieldLabel_6 = uilabel(app.GridLayout18_6);
            app.TotalnumberofiterationsEditFieldLabel_6.Text = 'Total number of iterations:';
            app.TimeVaryingTab = uitab(app.TabGroup2);
            app.TimeVaryingTab.Title = 'Time Varying';
            app.TVPGrid = uigridlayout(app.TimeVaryingTab);
            app.HyperparameterstimevaryingPanel = uipanel(app.TVPGrid);
            app.HyperparameterstimevaryingPanel.Title = 'Hyperparameters time varying';
            app.GridLayout17_2 = uigridlayout(app.HyperparameterstimevaryingPanel);
            app.tv_gamma = uieditfield(app.GridLayout17_2, 'numeric');
            app.tv_delta0 = uieditfield(app.GridLayout17_2, 'numeric');
            app.tv_alpha0 = uieditfield(app.GridLayout17_2, 'numeric');
            app.tv_alpha0.Value = 1;
            app.ARcoefficientonresidualvarianceEditFieldLabel_2 = uilabel(app.GridLayout17_2);
            app.ARcoefficientonresidualvarianceEditFieldLabel_2.Text = 'IG scale on residual variance';
            app.IGscaleonresidualvarianceEditFieldLabel_2 = uilabel(app.GridLayout17_2);
            app.IGscaleonresidualvarianceEditFieldLabel_2.Text = 'IG shape on residual variance';
            app.IGshapeonresidualvarianceEditFieldLabel_2 = uilabel(app.GridLayout17_2);
            app.IGshapeonresidualvarianceEditFieldLabel_2.Text = 'AR coefficient on residual variance';
            app.TimevaryingIRFsCheckBox = uicheckbox(app.GridLayout17_2);
            app.TimevaryingIRFsCheckBox.Text = 'Time-varying IRFs';
            app.TimevaryingIRFsCheckBox.Value = true;
            app.TimevaryingButtonGroup = uibuttongroup(app.TVPGrid);
            app.TimevaryingButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TimevaryingButtonGroupSelectionChanged, true);
            app.TimevaryingButtonGroup.Title = 'Time-varying';
            app.VARcoefficientsButton = uiradiobutton(app.TimevaryingButtonGroup);
            app.VARcoefficientsButton.Text = 'VAR coefficients';
            app.GeneralButton = uiradiobutton(app.TimevaryingButtonGroup);
            app.GeneralButton.Text = 'General';
            app.GeneralButton.Value = true;
            app.Iterations_4 = uipanel(app.TVPGrid);
            app.Iterations_4.Title = 'Iterations';
            app.GridLayout18_7 = uigridlayout(app.Iterations_4);
            app.Bu_4 = uieditfield(app.GridLayout18_7, 'numeric');
            app.Bu_4.ValueChangedFcn = createCallbackFcn(app, @Bu_4ValueChanged, true);
            app.Bu_4.Value = 1000;
            app.It_4 = uieditfield(app.GridLayout18_7, 'numeric');
            app.It_4.ValueChangedFcn = createCallbackFcn(app, @It_4ValueChanged, true);
            app.It_4.Value = 2000;
            app.NumberofburniniterationsEditFieldLabel_7 = uilabel(app.GridLayout18_7);
            app.NumberofburniniterationsEditFieldLabel_7.Text = 'Number of burn-in iterations';
            app.TotalnumberofiterationsEditFieldLabel_7 = uilabel(app.GridLayout18_7);
            app.TotalnumberofiterationsEditFieldLabel_7.Text = 'Total number of iterations:';
            app.MixedFrequencyTab = uitab(app.TabGroup2);
            app.MixedFrequencyTab.Title = 'Mixed Frequency';
            app.APPLICATIONSTab = uitab(app.TabGroup);
            app.APPLICATIONSTab.Title = 'APPLICATIONS';
            app.GridLayout14 = uigridlayout(app.APPLICATIONSTab);
            app.Interface_Applications = uipanel(app.GridLayout14);
            app.Structuralidentifications = uipanel(app.Interface_Applications);
            app.Structuralidentifications.Title = 'Structural identifications:';
            app.ButtonGroup = uibuttongroup(app.Structuralidentifications);
            app.Noidentification = uiradiobutton(app.ButtonGroup);
            app.Noidentification.Text = 'None';
            app.Noidentification.Value = true;
            app.Choleskifactorisation = uiradiobutton(app.ButtonGroup);
            app.Choleskifactorisation.Text = 'Choleski';
            app.Triangularfactorisation = uiradiobutton(app.ButtonGroup);
            app.Triangularfactorisation.Text = 'Triangular';
            app.Signrestrictions = uiradiobutton(app.ButtonGroup);
            app.Signrestrictions.Text = 'Sign restrictions';
            app.Proxy = uiradiobutton(app.ButtonGroup);
            app.Proxy.Text = 'Proxy SVAR';
            app.Proxysign = uiradiobutton(app.ButtonGroup);
            app.Proxysign.Text = 'Sign and proxy';
            app.Applicationoptions = uipanel(app.GridLayout14);
            app.Applicationoptions.Title = 'Application options                               FAVAR';
            app.ImpulseresponsefunctionsSwitchLabel = uilabel(app.Applicationoptions);
            app.ImpulseresponsefunctionsSwitchLabel.Text = 'Impulse response functions:';
            app.IRF = uiswitch(app.Applicationoptions, 'slider');
            app.IRF.Items = {'No', 'Yes'};
            app.IRF.ItemsData = {'0', '1'};
            app.IRF.Value = '1';
            app.UnconditionalforecastsSwitchLabel = uilabel(app.Applicationoptions);
            app.UnconditionalforecastsSwitchLabel.Text = 'Unconditional forecasts:';
            app.F = uiswitch(app.Applicationoptions, 'slider');
            app.F.Items = {'No', 'Yes'};
            app.F.ItemsData = {'0', '1'};
            app.F.Value = '1';
            app.HistoricaldecompositionsSwitchLabel = uilabel(app.Applicationoptions);
            app.HistoricaldecompositionsSwitchLabel.Text = ' Historical decompositions:';
            app.HD = uiswitch(app.Applicationoptions, 'slider');
            app.HD.Items = {'No', 'Yes'};
            app.HD.ItemsData = {'0', '1'};
            app.HD.Value = '1';
            app.ConditionalforecastsSwitchLabel = uilabel(app.Applicationoptions);
            app.ConditionalforecastsSwitchLabel.Text = '  Conditional forecasts:         ';
            app.CF = uiswitch(app.Applicationoptions, 'slider');
            app.CF.Items = {'No', 'Yes'};
            app.CF.ItemsData = {'0', '1'};
            app.CF.ValueChangedFcn = createCallbackFcn(app, @CFValueChanged, true);
            app.CF.Value = '1';
            app.ForecasterrorvarianceSwitchLabel = uilabel(app.Applicationoptions);
            app.ForecasterrorvarianceSwitchLabel.Text = 'Forecast error variance: ';
            app.FEVD = uiswitch(app.Applicationoptions, 'slider');
            app.FEVD.Items = {'No', 'Yes'};
            app.FEVD.ItemsData = {'0', '1'};
            app.FEVD.Value = '0';
            app.favarIRFplot = uicheckbox(app.Applicationoptions);
            app.favarIRFplot.Text = '';
            app.favarFEVDplot = uicheckbox(app.Applicationoptions);
            app.favarFEVDplot.Text = '';
            app.favarHDplot = uicheckbox(app.Applicationoptions);
            app.favarHDplot.Text = '';
            app.Periodoptions = uipanel(app.GridLayout14);
            app.Periodoptions.Title = 'Period options';
            app.IRFperiodsEditFieldLabel = uilabel(app.Periodoptions);
            app.IRFperiodsEditFieldLabel.Text = 'IRF periods:';
            app.IRFperiods = uieditfield(app.Periodoptions, 'numeric');
            app.IRFperiods.Value = 1;
            app.ForecastsafterlastsampleperiodSwitchLabel = uilabel(app.Periodoptions);
            app.ForecastsafterlastsampleperiodSwitchLabel.Text = 'Forecasts after last sample period:';
            app.Fendsmpl = uiswitch(app.Periodoptions, 'slider');
            app.Fendsmpl.Items = {'No', 'Yes'};
            app.Fendsmpl.Value = 'Yes';
            app.ForecastsStartdateEditFieldLabel = uilabel(app.Periodoptions);
            app.ForecastsStartdateEditFieldLabel.Text = 'Forecasts: Start date:';
            app.Fstartdate = uieditfield(app.Periodoptions, 'text');
            app.ForecastsEnddateLabel = uilabel(app.Periodoptions);
            app.ForecastsEnddateLabel.Text = 'Forecasts: End date:';
            app.Fenddate = uieditfield(app.Periodoptions, 'text');
            app.VARcoefficientsEditFieldLabel = uilabel(app.Periodoptions);
            app.VARcoefficientsEditFieldLabel.Text = 'Credibility Intervals:';
            app.cband = uieditfield(app.Periodoptions, 'numeric');
            app.cband.Value = 0.95;
            app.TypesofconditionalforecastsButtongroup = uibuttongroup(app.GridLayout14);
            app.TypesofconditionalforecastsButtongroup.Title = 'Types of conditional forecasts';
            app.Standardallshocks = uiradiobutton(app.TypesofconditionalforecastsButtongroup);
            app.Standardallshocks.Text = 'Standard (all shocks)';
            app.Standardallshocks.Value = true;
            app.Standardshockspecific = uiradiobutton(app.TypesofconditionalforecastsButtongroup);
            app.Standardshockspecific.Text = 'Standard (shock specific)';
            app.Tiltingmedian = uiradiobutton(app.TypesofconditionalforecastsButtongroup);
            app.Tiltingmedian.Text = 'Titling (median)';
            app.Tiltinginterval = uiradiobutton(app.TypesofconditionalforecastsButtongroup);
            app.Tiltinginterval.Text = 'Titling (interval)';
            app.Estimationoptions = uipanel(app.GridLayout14);
            app.Estimationoptions.Title = 'Estimation options:';
            app.ForecastevaluationsSwitchLabel = uilabel(app.Estimationoptions);
            app.ForecastevaluationsSwitchLabel.Text = 'Forecast evaluations:';
            app.Feval = uiswitch(app.Estimationoptions, 'slider');
            app.Feval.Items = {'No', 'Yes'};
            app.Feval.Value = 'Yes';
            app.ForecaststepaheadevaluationsEditFieldLabel = uilabel(app.Estimationoptions);
            app.ForecaststepaheadevaluationsEditFieldLabel.Text = 'Forecast step ahead evaluations:';
            app.hstep = uieditfield(app.Estimationoptions, 'numeric');
            app.hstep.Value = 1;
            app.RollingWindow0forfullsampleEditFieldLabel = uilabel(app.Estimationoptions);
            app.RollingWindow0forfullsampleEditFieldLabel.Text = 'Rolling Window (0 for full sample)';
            app.window_size = uieditfield(app.Estimationoptions, 'numeric');
            app.EvaluationSizeEditFieldLabel = uilabel(app.Estimationoptions);
            app.EvaluationSizeEditFieldLabel.Text = 'Evaluation Size';
            app.evaluation_size = uieditfield(app.Estimationoptions, 'numeric');
            app.evaluation_size.Value = 0.5;
            app.ProxyVars = uipanel(app.GridLayout14);
            app.ProxyVars.Title = 'Proxy SVAR options';
            app.InstrumentLabel = uilabel(app.ProxyVars);
            app.InstrumentLabel.Text = 'Instrument:';
            app.InstrumentstartdateLabel = uilabel(app.ProxyVars);
            app.InstrumentstartdateLabel.Text = 'Instrument: Start date:';
            app.startdateIV = uieditfield(app.ProxyVars, 'text');
            app.InstrumentEnddateLabel = uilabel(app.ProxyVars);
            app.InstrumentEnddateLabel.Text = 'Instrument: End date:';
            app.enddateIV = uieditfield(app.ProxyVars, 'text');
            app.Instrument = uieditfield(app.ProxyVars, 'text');
            app.FlatreducedformpriorLabel = uilabel(app.ProxyVars);
            app.FlatreducedformpriorLabel.Text = 'Flat reduced form prior:';
            app.prior_type_reduced_form = uiswitch(app.ProxyVars, 'slider');
            app.prior_type_reduced_form.Items = {'No', 'Yes'};
            app.prior_type_reduced_form.Value = 'Yes';
            app.HighrelevancepriorLabel = uilabel(app.ProxyVars);
            app.HighrelevancepriorLabel.Text = 'High relevance prior';
            app.prior_type_proxy = uiswitch(app.ProxyVars, 'slider');
            app.prior_type_proxy.Items = {'No', 'Yes'};
            app.prior_type_proxy.Value = 'Yes';
            app.CorrelShockLabel = uilabel(app.ProxyVars);
            app.CorrelShockLabel.Text = 'Correl Shock';
            app.Correlshock = uieditfield(app.ProxyVars, 'text');
            app.CorrelInstrumentLabel = uilabel(app.ProxyVars);
            app.CorrelInstrumentLabel.Text = 'Correl Instrument';
            app.Correlinstrument = uieditfield(app.ProxyVars, 'text');
            app.FAVARplotPanel = uipanel(app.GridLayout14);
            app.FAVARplotPanel.Title = 'FAVAR plot';
            app.PlotXLabel = uilabel(app.FAVARplotPanel);
            app.PlotXLabel.Text = 'Plot X';
            app.plotX = uieditfield(app.FAVARplotPanel, 'text');
            app.PlotXshockLabel = uilabel(app.FAVARplotPanel);
            app.PlotXshockLabel.Text = 'PlotX shock';
            app.plotXshock = uieditfield(app.FAVARplotPanel, 'text');
            app.REPLICATIONSTab = uitab(app.TabGroup);
            app.REPLICATIONSTab.Title = 'REPLICATIONS';
            app.ABOUTBEARTab = uitab(app.TabGroup);
            app.ABOUTBEARTab.Title = 'ABOUT BEAR';
            app.GridLayout = uigridlayout(app.ABOUTBEARTab);
            app.BAYESIANESTIMATIONANALYSISANDREGRESSIONBEARTOOLBOXLabel = uilabel(app.GridLayout);
            app.BAYESIANESTIMATIONANALYSISANDREGRESSIONBEARTOOLBOXLabel.Text = 'BAYESIAN ESTIMATION, ANALYSIS AND REGRESSION (BEAR) TOOLBOX';
            app.GridLayout12 = uigridlayout(app.GridLayout);
            app.ByAlistairDieppeandBjrnvanRoyeLabel = uilabel(app.GridLayout12);
            app.ByAlistairDieppeandBjrnvanRoyeLabel.Text = 'By Alistair Dieppe and Björn van Roye';
            app.Label = uilabel(app.GridLayout12);
            app.Label.Interpreter = 'html';
            app.Label.Text = {'<b>Authors:</b>'; ''; 'Alistair Dieppe (alistair.dieppe@ecb.europa.eu)'; 'Björn van Roye  (bvanroye@bloomberg.net)'; ''; '<b>Version:</b> 5.0'; ''; 'The updated version 5 of BEAR has benefitted from contributions from'; 'Boris Blagov, Marius Schulte and Ben Schumann.'; ''; 'This version builds-upon previous versions where Romain Legrand was'; 'instrumental in developing BEAR.'; ''; 'The authors are grateful to the following people for valuable input'; 'and advice which contributed to improve the quality of the toolbox:'; 'Paolo Bonomolo, Mirco Balatti, Marta Banbura, Niccolo Battistini,'; 'Gabriel Bobeica, Martin Bruns, Fabio Canova, Matteo Ciccarelli,'; 'Marek Jarocinski, Michele Lenza, Francesca Loria, Mirela Miescu,'; 'Gary Koop, Chiara Osbat, Giorgio Primiceri, Martino Ricci,'; 'Michal Rubaszek, Barbara Rossi, Fabian Schupp, Peter Welz and'; 'Hugo Vega de la Cruz.'; ''; 'These programmes are the responsibilities of the authors and not of'; 'the ECB and all errors and ommissions remain those of the authors.'; ''; 'Using the BEAR toolbox implies acceptance of the End User Licence'; 'Agreement and appropriate acknowledgement should be made.'; ''};
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = 'BEAR.PNG';
            % Show the figure after all components are created
            app.BEARinterfaceUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BEARapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BEARinterfaceUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BEARinterfaceUIFigure)
        end
    end
end