classdef favarSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FAVAROptionsUIFigure          matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        TcodesendoEditFieldLabel      matlab.ui.control.Label
        TcodesendoEditField           matlab.ui.control.EditField
        BlocknamesEditFieldLabel      matlab.ui.control.Label
        BlocknamesEditField           matlab.ui.control.EditField
        BlocknofactorsEditFieldLabel  matlab.ui.control.Label
        BlocknofactorsEditField       matlab.ui.control.EditField
        NofactorsEditFieldLabel       matlab.ui.control.Label
        NofactorsEditField            matlab.ui.control.NumericEditField
        blocksSwitchLabel             matlab.ui.control.Label
        blocksSwitch                  matlab.ui.control.Switch
        onestepSwitchLabel            matlab.ui.control.Label
        onestepSwitch                 matlab.ui.control.Switch
        slowfastSwitchLabel           matlab.ui.control.Label
        slowfastSwitch                matlab.ui.control.Switch
        TransformationSwitchLabel     matlab.ui.control.Label
        TransformationSwitch          matlab.ui.control.Switch
        PlotXshockEditFieldLabel      matlab.ui.control.Label
        PlotXshockEditField           matlab.ui.control.EditField
        PlotXEditFieldLabel           matlab.ui.control.Label
        PlotXEditField                matlab.ui.control.EditField
        SaveandCloseButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        favar bear.settings.FAVARsettings % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, favar)
            app.favar = favar;
            
            app.TransformationSwitch.ItemsData = [0, 1];
            app.slowfastSwitch.ItemsData = [0, 1];
            app.onestepSwitch.ItemsData = [0, 1];
            app.slowfastSwitch.ItemsData = [0, 1];
            app.blocksSwitch.ItemsData = [0, 1];
            
            app.TransformationSwitch.Value = favar.transformation;
            app.TcodesendoEditField.Value = favar.transform_endo;
            
            app.slowfastSwitch.Value = favar.slowfast;
            app.blocksSwitch.Value = favar.blocks;
            app.NofactorsEditField.Value = favar.numpc;
            app.BlocknamesEditField.Value = favar.blocknames;
            app.BlocknofactorsEditField.Value = favar.blocknumpc;
            app.PlotXEditField.Value = favar.plotX;
            app.PlotXshockEditField.Value = favar.plotXshock;
            
            if ~isa(favar, 'bear.settings.VARtypeSpecificFAVARsettings')                
                app.onestepSwitch.Enable = false;
                app.onestepSwitchLabel.Enable = false;
            else
               app.onestepSwitch.Value = favar.onestep; 
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FAVAROptionsUIFigure = uifigure('Visible', 'off');
            app.FAVAROptionsUIFigure.Name = 'FAVAR Options';
            app.GridLayout = uigridlayout(app.FAVAROptionsUIFigure);
            app.TcodesendoEditFieldLabel = uilabel(app.GridLayout);
            app.TcodesendoEditFieldLabel.Text = 'Tcodes endo';
            app.TcodesendoEditField = uieditfield(app.GridLayout, 'text');
            app.TcodesendoEditField.Tag = 'transform_endo';
            app.BlocknamesEditFieldLabel = uilabel(app.GridLayout);
            app.BlocknamesEditFieldLabel.Text = 'Block names';
            app.BlocknamesEditField = uieditfield(app.GridLayout, 'text');
            app.BlocknamesEditField.Tag = 'blocknames';
            app.BlocknamesEditField.Value = 'slow fast';
            app.BlocknofactorsEditFieldLabel = uilabel(app.GridLayout);
            app.BlocknofactorsEditFieldLabel.Text = 'Block no. factors';
            app.BlocknofactorsEditField = uieditfield(app.GridLayout, 'text');
            app.BlocknofactorsEditField.Tag = 'blocknumpc';
            app.BlocknofactorsEditField.Value = '2 2';
            app.NofactorsEditFieldLabel = uilabel(app.GridLayout);
            app.NofactorsEditFieldLabel.Text = 'No. factors';
            app.NofactorsEditField = uieditfield(app.GridLayout, 'numeric');
            app.NofactorsEditField.Limits = [0 Inf];
            app.NofactorsEditField.Tag = 'numpc';
            app.NofactorsEditField.Value = 4;
            app.blocksSwitchLabel = uilabel(app.GridLayout);
            app.blocksSwitchLabel.Text = 'blocks';
            app.blocksSwitch = uiswitch(app.GridLayout, 'slider');
            app.blocksSwitch.Items = {'No', 'Yes'};
            app.blocksSwitch.ItemsData = {'0', '1'};
            app.blocksSwitch.Tag = 'blocks';
            app.blocksSwitch.Value = '0';
            app.onestepSwitchLabel = uilabel(app.GridLayout);
            app.onestepSwitchLabel.Text = 'onestep';
            app.onestepSwitch = uiswitch(app.GridLayout, 'slider');
            app.onestepSwitch.Items = {'No', 'Yes'};
            app.onestepSwitch.ItemsData = {'0', '1'};
            app.onestepSwitch.Tag = 'onestep';
            app.onestepSwitch.Value = '1';
            app.slowfastSwitchLabel = uilabel(app.GridLayout);
            app.slowfastSwitchLabel.Text = 'slowfast';
            app.slowfastSwitch = uiswitch(app.GridLayout, 'slider');
            app.slowfastSwitch.Items = {'No', 'Yes'};
            app.slowfastSwitch.ItemsData = {'0', '1'};
            app.slowfastSwitch.Tag = 'slowfast';
            app.slowfastSwitch.Value = '1';
            app.TransformationSwitchLabel = uilabel(app.GridLayout);
            app.TransformationSwitchLabel.Text = 'Transformation';
            app.TransformationSwitch = uiswitch(app.GridLayout, 'slider');
            app.TransformationSwitch.Items = {'No', 'Yes'};
            app.TransformationSwitch.ItemsData = {'0', '1'};
            app.TransformationSwitch.Tag = 'transformation';
            app.TransformationSwitch.Value = '0';
            app.PlotXshockEditFieldLabel = uilabel(app.GridLayout);
            app.PlotXshockEditFieldLabel.Text = 'PlotX shock';
            app.PlotXshockEditField = uieditfield(app.GridLayout, 'text');
            app.PlotXshockEditField.Tag = 'plotXshock';
            app.PlotXEditFieldLabel = uilabel(app.GridLayout);
            app.PlotXEditFieldLabel.Text = 'PlotX';
            app.PlotXEditField = uieditfield(app.GridLayout, 'text');
            app.PlotXEditField.Tag = 'plotX';
            app.SaveandCloseButton = uibutton(app.GridLayout, 'push');
            app.SaveandCloseButton.Text = 'Save and Close';
            % Show the figure after all components are created
            app.FAVAROptionsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = favarSettings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FAVAROptionsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FAVAROptionsUIFigure)
        end
    end
end